classdef Tugas4_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        FruitClassificationTab      matlab.ui.container.Tab
        GridLayout                  matlab.ui.container.GridLayout
        ClassificationLabel         matlab.ui.control.Label
        ClassificationTitleLabel    matlab.ui.control.Label
        ControlPanel                matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        StatusLabel                 matlab.ui.control.Label
        LoadModelButton             matlab.ui.control.Button
        StatusLamp                  matlab.ui.control.Lamp
        TrainModelButton            matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        ClassifyImageButton         matlab.ui.control.Button
        LoadImageButton             matlab.ui.control.Button
        UIAxesEdge                  matlab.ui.control.UIAxes
        UIAxesOriginal              matlab.ui.control.UIAxes
        UIAxesFill                  matlab.ui.control.UIAxes
        UIAxesSegmented             matlab.ui.control.UIAxes
        PlateRecognitionTab         matlab.ui.container.Tab
        GridLayout_2                matlab.ui.container.GridLayout
        ClassificationLabel_2       matlab.ui.control.Label
        ClassificationTitleLabel_2  matlab.ui.control.Label
        ControlPanel_2              matlab.ui.container.Panel
        GridLayout2_2               matlab.ui.container.GridLayout
        StatusLabel_2               matlab.ui.control.Label
        StatusLamp_2                matlab.ui.control.Lamp
        ResetButton_2               matlab.ui.control.Button
        SegmentImageButton          matlab.ui.control.Button
        LoadImageButton_2           matlab.ui.control.Button
        UIAxesOriginal_2            matlab.ui.control.UIAxes
        UIAxesSegmented_2           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % --- Fruits Properties ---
        OriginalImage    % RGB or grayscale original image
        GrayImage        % Grayscale version of OriginalImage
        EdgeImage        % Binary or grayscale edge image
        MaskedImage      % Masked image for segmentation control
        SegmentedImage   % Segmentation result

        SavedModel       % Prediction Model
        ClassNames
        % --- End of Fruits Properties ---

        % --- Plate Number Properties ---
        OriginalImage_2    % RGB or grayscale original image
        GrayImage_2        % Grayscale version of OriginalImage
        EdgeImage_2        % Binary or grayscale edge image
        MaskedImage_2      % Masked image for segmentation control
        SegmentedImage_2   % Segmentation result
        % --- End of Plate Number Properties ---
    end

    methods (Access = private)
        function E = edgeCanny(~, f)
            E = edge(f, 'canny');
        end

        function textOut = recognize_plate(app, img)
            % img should be binary
            bw = img;

            % --- Extract characters by connected components ---
            cc = bwconncomp(bw);
            props = regionprops(cc, 'BoundingBox');

            % --- Debug
            % length(props)
            % disp(cc);

            % allBoxes = reshape([props.BoundingBox], 4, [])';
            % imgWithBoxes = insertShape(img, 'Rectangle', allBoxes, ...
            %                            'Color', 'red', 'LineWidth', 2);
            % figure, imshow(imgWithBoxes);
            % -----

            % Sort left to right
            [~, idx] = sort(arrayfun(@(p)p.BoundingBox(1), props));
            props = props(idx);

            % --- Load char templates ---
            templates = app.load_templates();

            resultChars = strings(1, length(props));

            for i = 1:length(props)
                bb = props(i).BoundingBox;
                charImg = imcrop(bw, bb);
                charImg = imresize(charImg, [60 30]); % standard size
                
                % figure, imshow(charImg);

                bestScore = 0;
                bestChar = "";
                bestImg = [];

                for t = 1:length(templates)
                    tmplImg = templates{t}.img;
                    tmplLabel = templates{t}.label;

                    % figure, imshow(tmplImg);

                    simScore = sum(~xor(charImg, tmplImg), 'all');

                    if simScore > bestScore
                        bestScore = simScore;
                        bestChar = tmplLabel;
                        bestImg = tmplImg;
                    end
                end

                % figure, imshow(charImg);
                % figure, imshow(bestImg);

                if bestScore > 1200
                    resultChars(i) = bestChar;
                else 
                    resultChars(i) = "";
                end
            end

            % Join characters into final text
            textOut = strjoin(resultChars, "");
        end

        function templates = load_templates(app)
            templates = {};

            files = dir('./templates/*.png');

            if isempty(files)
                templates = {};
                uialert(app.UIFigure, ...
                    'No template files found. Place character PNG images in the "templates" folder.', ...
                    'Error: No Templates', 'Icon', 'error');
                return
            end

            [~, idx] = sort({files.name});
            files = files(idx);

            for i = 1:numel(files)
                imgPath = fullfile(files(i).folder, files(i).name);
                img = imread(imgPath);
                if ndims(img) == 3
                    img = rgb2gray(img);
                end

                bw = img > 128;
                bw = imresize(bw, [60 30]);

                % Extract label from filename before ".png"
                [~, name, ~] = fileparts(files(i).name);

                templates{i}.img = bw;
                templates{i}.label = name;   % "0", "A", "B", etc.
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [fname, fpath] = uigetfile({'*.jpg;*.png;*.bmp;*.jpeg','Image Files'}, ...
                                       'Select Input Image');
            if isequal(fname,0)
                return; % user cancelled
            end
            
            fullPath = fullfile(fpath, fname);
            img = imread(fullPath);
            
            app.OriginalImage = img;
            
            % Convert to grayscale if needed
            if size(img, 3) == 3
                app.GrayImage = rgb2gray(img);
            else
                app.GrayImage = img;
            end
            
            % Display original
            imshow(app.OriginalImage, 'Parent', app.UIAxesOriginal);
        end

        % Button pushed function: ClassifyImageButton
        function ClassifyImageButtonPushed(app, event)
            if isempty(app.GrayImage)
                uialert(app.UIFigure, 'Please load an image first.', 'No Image');
                return;
            end
            
            % Normalize image to double [0,1] just in case
            f = im2double(app.GrayImage);
            
            app.EdgeImage = app.edgeCanny(f);
            
            % Show edge image
            imshow(app.EdgeImage, 'Parent', app.UIAxesEdge);

            % Make sure we have edges
            if isempty(app.EdgeImage)
                uialert(app.UIFigure, 'Please detect edges first.', 'No Edge Image');
                return;
            end
        
            edgeImg = app.EdgeImage;
        
            % Close edges by radius strelC
            gap_size = 5;
           
            se = strel('disk', gap_size);
            % closedEdges = imdilate(edgeImg, se);
            closedEdges = imclose(edgeImg, se);

            % Fill up closed edges
            filled = imfill(closedEdges, 'holes');

            X = 100;

            % Keep objects with area >= X pixels
            mask = bwareaopen(filled, X);

            app.MaskedImage = mask;
            imshow(app.MaskedImage, 'Parent', app.UIAxesFill);
            title(app.UIAxesFill, 'Fill Image');
            % figure, imshow();
        
            if isempty(app.OriginalImage)
                uialert(app.UIFigure, 'Original image is not available.', 'Error');
                return;
            end
        
            rgb = app.OriginalImage;
            % Ensure we have an RGB image for display
            if size(rgb, 3) == 1
                rgb = cat(3, rgb, rgb, rgb);
            end
        
            segmented = rgb;
            % Set background (mask == 0) to black
            for c = 1:3
                temp = segmented(:, :, c);
                temp(~mask) = 0;
                segmented(:, :, c) = temp;
            end
        
            % Store and display the result
            app.SegmentedImage = segmented;
            imshow(app.SegmentedImage, 'Parent', app.UIAxesSegmented);
            title(app.UIAxesSegmented, 'Segmented Image');

            % Predictions
            if isempty(app.SavedModel)
                uialert(app.UIFigure, 'Train or Load a model first.', 'Error');
                return;
            end
            net = app.SavedModel;

            % Resize Image
            inputSize = net.Layers(1).InputSize(1:2);
            resizedImg = imresize(app.SegmentedImage, inputSize);

            % Convert the image to the required data type (single)
            imgPred = single(resizedImg);

            Yscores = predict(net, imgPred);
            [maxScores, idx] = max(Yscores, [], 2); 

            % Set the confidence threshold 
            threshold = 0.5; 
            otherLabel = categorical({'others'});

            Ypred = app.ClassNames(idx);

            % Apply rejection logic
            lowConfidenceIndices = maxScores < threshold;
            Ypred(lowConfidenceIndices) = otherLabel;

            % Output Prediction
            app.ClassificationLabel.Text = char(Ypred);
        end

        % Callback function
        function SegmentButtonPushed(app, event)
            % % Make sure we have edges
            % if isempty(app.EdgeImage)
            %     uialert(app.UIFigure, 'Please detect edges first.', 'No Edge Image');
            %     return;
            % end
            % 
            % edgeImg = app.EdgeImage;
            % 
            % % Close edges by radius strelC
            % se = strel('disk', 2);
            % % closedEdges = imdilate(edgeImg, se);
            % closedEdges = imclose(edgeImg, se);
            % 
            % % Fill up closed edges
            % filled = imfill(closedEdges, 'holes');
            % 
            % X = app.AreaOpenSlider.Value;
            % 
            % % Keep objects with area >= X pixels
            % mask = bwareaopen(filled, X);
            % 
            % app.MaskedImage = mask;
            % imshow(app.MaskedImage, 'Parent', app.UIAxesFill);
            % title(app.UIAxesFill, 'Fill Image');
            % % figure, imshow();
            % 
            % if isempty(app.OriginalImage)
            %     uialert(app.UIFigure, 'Original image is not available.', 'Error');
            %     return;
            % end
            % 
            % rgb = app.OriginalImage;
            % % Ensure we have an RGB image for display
            % if size(rgb, 3) == 1
            %     rgb = cat(3, rgb, rgb, rgb);
            % end
            % 
            % segmented = rgb;
            % % Set background (mask == 0) to black
            % for c = 1:3
            %     temp = segmented(:, :, c);
            %     temp(~mask) = 0;
            %     segmented(:, :, c) = temp;
            % end
            % 
            % % Store and display the result
            % app.SegmentedImage = segmented;
            % imshow(app.SegmentedImage, 'Parent', app.UIAxesSegmented);
            % title(app.UIAxesSegmented, 'Segmented Image');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.OriginalImage  = [];
            app.GrayImage      = [];
            app.EdgeImage      = [];
            app.MaskedImage    = [];
            app.SegmentedImage = [];
            
            cla(app.UIAxesOriginal, 'reset');
            cla(app.UIAxesEdges, 'reset');
            cla(app.UIAxesMasked, 'reset');
            cla(app.UIAxesSegmented, 'reset');
            
            title(app.UIAxesOriginal, 'Original Image');
            title(app.UIAxesEdges, 'Edge Image');
            title(app.UIAxesFill, 'Masked Image');
            title(app.UIAxesSegmented, 'Segmented Object');

            app.ClassificationLabel.Text = '...';
        end

        % Callback function
        function OperatorDropDownValueChanged(app, event)
            % app.cconstantEditField.Enable = "off";
            % app.sigmaEditField.Enable = "off";
            % app.AutoThresholdCheckBox.Enable = "off";
            % 
            % op = app.OperatorDropDown.Value;
            % 
            % % Choose a suggested default threshold depending on operator
            % switch op
            %     case 'Laplace'
            %         defaultT = 0.20;
            %     case 'LoG'
            %         defaultT = 0.20;
            %         app.sigmaEditField.Enable = "on";
            %     case 'Sobel'
            %         defaultT = 0.15;
            %         app.cconstantEditField.Enable = "on";
            %     case {'Prewitt', 'Roberts'}
            %         defaultT = 0.15;
            %     case 'Canny'
            %         defaultT = 0.10;
            %         app.sigmaEditField.Enable = "on";
            %         app.AutoThresholdCheckBox.Enable = "on";
            %     otherwise
            %         defaultT = app.ThresholdSlider.Value;
            % end
            % 
            % % Keep it in [0,1] just to be safe
            % defaultT = max(0, min(1, defaultT));
            % 
            % % Update both controls so they stay in sync
            % app.ThresholdSlider.Value      = defaultT;
            % app.ThresholdEditField.Value   = defaultT;
        end

        % Callback function
        function ThresholdSliderValueChanged(app, event)
            % value = app.ThresholdSlider.Value;
            % app.ThresholdEditField.Value = value;
        end

        % Callback function
        function ThresholdEditFieldValueChanged(app, event)
            % value = app.ThresholdEditField.Value;
            % 
            % % Clip 
            % value = max(0, min(1, value));
            % app.ThresholdEditField.Value = value;
            % app.ThresholdSlider.Value = value;
        end

        % Callback function
        function AreaOpenSliderValueChanged(app, event)
            % value = app.AreaOpenSlider.Value;
            % app.AreaOpenEditField.Value = value;
        end

        % Callback function
        function AreaOpenEditFieldValueChanged(app, event)
            % value = app.AreaOpenEditField.Value;
            % 
            % % Clip 
            % value = max(0, min(numel(app.GrayImage), value));
            % app.AreaOpenEditField.Value = value;
            % app.AreaOpenSlider.Value = value;
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed(app, event)
            % start_path = 'C:';
            dialog_title = 'Select Input Train Folder';
            
            folder_path = uigetdir(dialog_title);
            
            if folder_path == 0
               % User cancel
               return;
            end

            % Change Lamp State
            app.StatusLamp.Color = 'yellow';
            app.StatusLabel.Text = 'Training in progress';

            % Load dataset
            imds = imageDatastore(folder_path, "IncludeSubfolders", true, 'LabelSource','foldernames');
            inputSize = [227 227 3];

            augimds = augmentedImageDatastore(inputSize(1:2), imds);

            layers = [
                imageInputLayer(inputSize)
                convolution2dLayer(11, 8, 'Padding','same')
                batchNormalizationLayer
                reluLayer
                maxPooling2dLayer(2, Stride=2)

                dropoutLayer(0.1)

                convolution2dLayer(5, 16, 'Padding','same')
                batchNormalizationLayer
                reluLayer
                maxPooling2dLayer(2, Stride=2)

                dropoutLayer(0.1)

                convolution2dLayer(3, 32, 'Padding','same')
                batchNormalizationLayer
                reluLayer

                dropoutLayer(0.1)

                convolution2dLayer(3, 32, 'Padding','same')
                batchNormalizationLayer
                reluLayer

                % convolution2dLayer(3, 16, 'Padding','same')
                % batchNormalizationLayer
                % reluLayer

                dropoutLayer(0.2)

                fullyConnectedLayer(5)
                dropoutLayer(0.5)

                fullyConnectedLayer(3)
                softmaxLayer('Name','fc_output')
                ];

            classNames = unique(imds.Labels, 'stable'); 
            customWeights = [2.0; 2.0; 2.0];

            % Convert the layer array to a layer graph
            lgraph = layerGraph(layers);
            
            % Create weighted classification layer
            weightedClassificationLayer = classificationLayer(...
                'Classes', classNames, ...
                'ClassWeights', customWeights, ...
                'Name', 'weighted_output');
            
            % Replace the final layer of the graph 
            lgraph = addLayers(lgraph, weightedClassificationLayer);
            
            % Connect the new layer to the fully connected layer
            lgraph = connectLayers(lgraph, 'fc_output', 'weighted_output');
            
            options = trainingOptions("adam", ...
                MaxEpochs=10, ...
                InitialLearnRate=1e-4, ...
                Plots="training-progress", ...
                Verbose=false);
            
            net = trainNetwork(augimds, lgraph, options);

            % Change Lamp State
            app.StatusLamp.Color = 'green';
            app.StatusLabel.Text = 'Model Trained';

            % Save model
            app.SavedModel = net;
            app.ClassNames = classNames;
            save('model.mat', 'net', 'classNames');
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            [fname, fpath] = uigetfile({'*.mat;','Mat Files'}, ...
                                       'Select Input Model');
            if isequal(fname,0)
                return; % user cancelled
            end
            
            fullPath = fullfile(fpath, fname);
            load(fullPath, 'net', 'classNames');

            % Save Model
            app.SavedModel = net;
            app.ClassNames = classNames;

            % Change Lamp State
            app.StatusLamp.Color = 'green';
            app.StatusLabel.Text = 'Model Trained';
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            
        end

        % Button pushed function: SegmentImageButton
        function SegmentImageButtonPushed(app, event)
            if isempty(app.GrayImage_2)
                uialert(app.UIFigure, 'Original image is not available.', 'Error');
                return;
            end

            % --- Edge Based Segmentation --- to detect region of interest

            if isempty(app.GrayImage_2)
                uialert(app.UIFigure, 'Please load an image first.', 'No Image');
                return;
            end

            % Normalize image to double [0,1] just in case
            f = im2double(app.GrayImage_2);

            app.EdgeImage_2 = app.edgeCanny(f);

            % Make sure we have edges
            if isempty(app.EdgeImage_2)
                uialert(app.UIFigure, 'Please detect edges first.', 'No Edge Image');
                return;
            end

            edgeImg = app.EdgeImage_2;

            % Close edges by radius strelC
            gap_size = 5;

            se = strel('disk', gap_size);
            % closedEdges = imdilate(edgeImg, se);
            closedEdges = imclose(edgeImg, se);

            % Fill up closed edges
            filled = imfill(closedEdges, 'holes');

            X = 100;

            % Keep objects with area >= X pixels
            mask = bwareaopen(filled, X);

            app.MaskedImage_2 = mask;
            % figure, imshow();

            segmented = app.GrayImage_2;
            % Set background (mask == 0) to black
            temp = segmented(:, :);
            temp(~mask) = 0;
            segmented(:, :) = temp;

            % --- End of Edge Based Segmentation ---

            % --- Threshold Segmentation --- further segment image
            
            BW = segmented > 128;
            % figure, imshow(BW);

            img = BW;
            if isinteger(img)
                img_logical = (img == 255);
            else
                img_logical = img;
            end

            N_white = sum(img_logical(:));
            N_black = numel(img) - N_white;

            if N_black > N_white
                % black background

                % --- Decide threshold for open area
                totalArea = numel(img);
                openFactor = 2.2/1000;
                openThreshold = round(openFactor * totalArea);
                % -----
                
                % --- Decide gap size for imopen 
                [h, w, ~] = size(img);
                base = min(h,w);
                gapFactor = 2.5/100;
                gap_size = round(base * gapFactor);
                % -----

                % Open edges by radius 
                se = strel('disk', gap_size);
                morphedEdges = imopen(img, se);
                % figure, imshow(morphedEdges);
                
                % % Fill up closed edges
                % filled = imfill(closedEdges, 'holes');
    
                % Keep objects with area >= X pixels
                mask = bwareaopen(morphedEdges, openThreshold);
                % figure, imshow(mask);

                app.SegmentedImage_2 = mask;
            else 
                % white background

                % X = 1000;
                % 
                % % Keep objects with area >= X pixels
                % open = bwareaopen(img, X);
                % figure, imshow(open);

                % Invert image so black number is white
                inverted = imcomplement(img);

                % --- Decide threshold for open area
                totalArea = numel(img);
                openFactor = 6.6/1000;
                openThreshold = round(openFactor * totalArea);
                % -----

                % openThreshold = 300;
    
                % Keep objects with area >= X pixels
                mask = bwareaopen(inverted, openThreshold);

                app.SegmentedImage_2 = mask;
            end

            imshow(app.SegmentedImage_2, 'Parent', app.UIAxesSegmented_2);
            title(app.UIAxesSegmented_2, 'Segmented Image');

            text = app.recognize_plate(app.SegmentedImage_2);
            app.ClassificationLabel_2.Text = text;
        end

        % Button pushed function: LoadImageButton_2
        function LoadImageButton_2Pushed(app, event)
            [fname, fpath] = uigetfile({'*.jpg;*.png;*.bmp;*.jpeg','Image Files'}, ...
                                       'Select Input Image');
            if isequal(fname,0)
                return; % user cancelled
            end
            
            fullPath = fullfile(fpath, fname);
            img = imread(fullPath);
            
            app.OriginalImage_2 = img;
            
            % Convert to grayscale if needed
            if size(img, 3) == 3
                app.GrayImage_2 = rgb2gray(img);
            else
                app.GrayImage_2 = img;
            end
            
            % Display original
            imshow(app.OriginalImage_2, 'Parent', app.UIAxesOriginal_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FruitClassificationTab = uitab(app.TabGroup);
            app.FruitClassificationTab.Title = 'Fruit Classification';
            app.GridLayout = uigridlayout(app.FruitClassificationTab);
            app.UIAxesSegmented = uiaxes(app.GridLayout);
            title(app.UIAxesSegmented, 'Segmented Image')
            xlabel(app.UIAxesSegmented, 'X')
            ylabel(app.UIAxesSegmented, 'Y')
            zlabel(app.UIAxesSegmented, 'Z')
            app.UIAxesFill = uiaxes(app.GridLayout);
            title(app.UIAxesFill, 'Fill Image')
            xlabel(app.UIAxesFill, 'X')
            ylabel(app.UIAxesFill, 'Y')
            zlabel(app.UIAxesFill, 'Z')
            app.UIAxesOriginal = uiaxes(app.GridLayout);
            title(app.UIAxesOriginal, 'Original Image')
            xlabel(app.UIAxesOriginal, 'X')
            ylabel(app.UIAxesOriginal, 'Y')
            zlabel(app.UIAxesOriginal, 'Z')
            app.UIAxesEdge = uiaxes(app.GridLayout);
            title(app.UIAxesEdge, 'Edge Image')
            xlabel(app.UIAxesEdge, 'X')
            ylabel(app.UIAxesEdge, 'Y')
            zlabel(app.UIAxesEdge, 'Z')
            app.ControlPanel = uipanel(app.GridLayout);
            app.ControlPanel.Title = 'Control Panel';
            app.GridLayout2 = uigridlayout(app.ControlPanel);
            app.LoadImageButton = uibutton(app.GridLayout2, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ClassifyImageButton = uibutton(app.GridLayout2, 'push');
            app.ClassifyImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyImageButtonPushed, true);
            app.ClassifyImageButton.Text = 'Classify Image';
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TrainModelButton = uibutton(app.GridLayout2, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainModelButton.Text = 'Train Model';
            app.StatusLamp = uilamp(app.GridLayout2);
            app.LoadModelButton = uibutton(app.GridLayout2, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            app.StatusLabel = uilabel(app.GridLayout2);
            app.StatusLabel.Text = 'Untrained';
            app.ClassificationTitleLabel = uilabel(app.GridLayout);
            app.ClassificationTitleLabel.Text = 'Classification';
            app.ClassificationLabel = uilabel(app.GridLayout);
            app.ClassificationLabel.Text = '...';
            app.PlateRecognitionTab = uitab(app.TabGroup);
            app.PlateRecognitionTab.Title = 'Plate Recognition';
            app.GridLayout_2 = uigridlayout(app.PlateRecognitionTab);
            app.UIAxesSegmented_2 = uiaxes(app.GridLayout_2);
            title(app.UIAxesSegmented_2, 'Segmented Image')
            xlabel(app.UIAxesSegmented_2, 'X')
            ylabel(app.UIAxesSegmented_2, 'Y')
            zlabel(app.UIAxesSegmented_2, 'Z')
            app.UIAxesOriginal_2 = uiaxes(app.GridLayout_2);
            title(app.UIAxesOriginal_2, 'Original Image')
            xlabel(app.UIAxesOriginal_2, 'X')
            ylabel(app.UIAxesOriginal_2, 'Y')
            zlabel(app.UIAxesOriginal_2, 'Z')
            app.ControlPanel_2 = uipanel(app.GridLayout_2);
            app.ControlPanel_2.Title = 'Control Panel';
            app.GridLayout2_2 = uigridlayout(app.ControlPanel_2);
            app.LoadImageButton_2 = uibutton(app.GridLayout2_2, 'push');
            app.LoadImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButton_2Pushed, true);
            app.LoadImageButton_2.Text = 'Load Image';
            app.SegmentImageButton = uibutton(app.GridLayout2_2, 'push');
            app.SegmentImageButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentImageButtonPushed, true);
            app.SegmentImageButton.Text = 'Segment Image';
            app.ResetButton_2 = uibutton(app.GridLayout2_2, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.StatusLamp_2 = uilamp(app.GridLayout2_2);
            app.StatusLabel_2 = uilabel(app.GridLayout2_2);
            app.StatusLabel_2.Text = 'Model Not Loaded';
            app.ClassificationTitleLabel_2 = uilabel(app.GridLayout_2);
            app.ClassificationTitleLabel_2.Text = 'Plate Number';
            app.ClassificationLabel_2 = uilabel(app.GridLayout_2);
            app.ClassificationLabel_2.Text = '...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tugas4_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end